classdef Accel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AccelerationLabel            matlab.ui.control.Label
        VehicleMasskgEditFieldLabel  matlab.ui.control.Label
        VehicleMasskgEditField       matlab.ui.control.NumericEditField
        DriverMasskgEditFieldLabel   matlab.ui.control.Label
        DriverMasskgEditField        matlab.ui.control.NumericEditField
        WheelbasemEditFieldLabel     matlab.ui.control.Label
        WheelbasemEditField          matlab.ui.control.NumericEditField
        TrackmEditFieldLabel         matlab.ui.control.Label
        TrackmEditField              matlab.ui.control.NumericEditField
        CalculateButton              matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        TimesEditFieldLabel          matlab.ui.control.Label
        TimesEditField               matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

% Epreuve et pilotage
%D_tot = 75; % Longueur de la piste (m)
r_pat = 10500; % Regime de patinage de l'embrayage (tr/min)
m_p = app.DriverMasskgEditField.Value ; % Masse du pilote (kg)
h_g_p = 0.42; % Hauteur du centre de gravite du pilote (m)
k = 1; % Rapport engage au depart
k_max = 4; % Rapport maximum

% Base roulante
m_v = app.VehicleMasskgEditField.Value; % Masse du vehicule (kg)
h_g_v = 0.31; % Hauteur du centre de gravite de la voiture (m)
emp = app.WheelbasemEditField.Value; % Empattement (m)
rep = 0.57; % Masse sur l'essieu arri?re en statique (%)
D_roue = 0.52; % Diametre exterieur de la roue (m)
Jjp = 0.313; %Inertie de la roue et du pneu (CATIA)
Jm = 0.005; %Inertie du moyeu et du freinage (CATIA)
Ja = 0.00005; %inertie d'un arbre de transmission  (CATIA)
Jd = 0.03; %Inertie du diff?rentiel (CATIA)
Jc = 0.005; % Inertie de la couronne (CATIA)
Jmot = 0.1; % Estimation de l'inertie moteur+ chaine
J_rot = 2*(Jjp+Jm+Ja)+Jd+Jc+Jmot; % Inertie equivalente des masses en rotation (kg.m?)

% Pneumatiques
% Pas de glissement
coeff_adh = 1.55;
sl =0;

coeff_roul = 0.01; % Coefficient de resistance au roulement du pneu

rmot = 1.0e+04 *[ 0 0.5038    0.5127    0.5214    0.5289    0.5348    0.5411    0.5490    0.5565    0.5620    0.5664    0.5707    0.5761    0.5784    0.5861    0.5936    0.5995    0.6066    0.6152    0.6227    0.6283    0.6334    0.6397    0.6476    0.6562    0.6635    0.6736    0.6823    0.6911    0.6971    0.7059    0.7146    0.7233    0.7319    0.7406    0.7493    0.7580    0.7666    0.7753    0.7840    0.7926    0.8013    0.8100    0.8187    0.8273    0.8360    0.8447    0.8534    0.8620    0.8707    0.8794    0.8881    0.8967    0.9054    0.9141    0.9227    0.9314    0.9401    0.9488    0.9574    0.9661    0.9748    0.9835    0.9921    1.0008    1.0095    1.0182    1.0268    1.0355    1.0442    1.0528    1.0615    1.0702    1.0789    1.0875    1.0962    1.1049    1.1136    1.1222    1.1309    1.1396    1.1482    1.1569    1.1656    1.1743    1.1829    1.1916    1.2003    1.2090    1.2176    1.2263    1.2350    1.2437    1.2523    1.2610    1.2697    1.2783    1.2870    1.2957    1.3040    1.3123    1.3209    1.3276    1.3312    1.3328    1.3343    1.3356    1.3367];
cmot = [ 0 38.5487   38.6362   38.6097   38.0906   37.3282   36.4654   35.8732   36.2616   37.0797   37.8877   38.7200   39.6658   39.6542   39.8572   39.2645   38.4332   37.5575   37.3589   38.0997   38.9081   39.7279   40.5804   41.3131   41.6142   41.4875   41.0424   41.0292   41.3002   41.8163   41.8127   42.3240   42.7966   43.3348   43.9635   44.6253   45.3373   46.0912   46.8026   47.4644   48.0412   48.4537   48.7615   48.9302   48.9964   48.9964   48.9534   48.8640   48.7416   48.7052   48.7052   48.7019   48.6291   48.5558   48.3909   48.1592   47.8813   47.5934   47.3188   47.1269   46.9151   46.6835   46.4419   46.1673   45.9522   45.7801   45.6026   45.5187   45.3731   45.1978   45.0125   44.8487   44.5790   44.3771   44.2514   44.1422   44.0032   43.8742   43.7385   43.5367   43.3348   43.0867   42.8021   42.4845   42.1668   41.8558   41.6837   41.4455   41.1410   40.8333   40.4660   40.1164   39.8048   39.4094   38.9671   38.4112   37.8057   37.3126   36.8229   36.1515   35.3504   34.7433   34.1138   33.2118   32.1896   30.9248   29.6618   28.5286];

% % 
r_rupteur = 13500; % Regime de rupteur (tr/min)
t_pas = 0.1; % Temps de passage de rapport (s)

% Transmission
k_p = 36/76; % Rapport primaire

% Etagement origine
K(1) = 12/32; % Rapport de 1ere
K(2) = 16/31; % Rapport de 2eme
K(3) = 18/29; % Rapport de 3eme
K(4) = 22/31; % Rapport de 4eme
K(5) = 23/29; % Rapport de 5eme
K(6) = 24/28; % Rapport de 6eme

k_f = 1/3.2; % Rapport final

pertes = 0.9; % Coefficient de pertes de couple dans la transmission

%% 

% Vitesses (m/s)
for i = 1:6
vitesse(:,i) = rmot*k_p*K(i)*k_f*D_roue*3.14/60;
end

% Couples aux roues (m.kg)
for i = 1:6
couple(:,i) = cmot*pertes/(k_p*K(i)*k_f);
end

g = 9.81; % Pesanteur (m/s?)
m = m_v + m_p; % Masse totale (kg)
h_g = (m_v*h_g_v+m_p*h_g_p)/m; % Hauteur du centre de gravite (m)

b = coeff_roul*2/D_roue; % Decalage du point d'appui (m)
c_roul = m*g*b; % Resistance au roulement (N.m)

% Inertie

J_trans = m*D_roue*D_roue/4; % Inertie equivalente des masses en translation (kg.m?)
J_eq = J_trans + J_rot; % Inertie totale (kg.m?)

%% Param?tres simulation

pas = 0.001; % Pas de simulation (s)
n = t_pas/pas; % Nombre de pas necessaire au passage de rapport

% Initialisation

d = 0; % distance parcourue
r = r_pat; % regime moteur au depart
v = 0; % vitesse du vehicule
t = -pas; % temps
j = 0; % Numero du point de fonctionnement du moteur
T = [0]; % Temps
V = [0]; % Vitesse
a = 0;
A = [0]; % Acceleration en g
R = [r_pat]; % Regime
Ke = [k]; % Rapport engage
C = [0]; % Couple
D = [0]; % Distance
R_pas = []; % Regimes de passage de rapport
T_pas = [];
E = [1]; % Embrayage 1=debraye, 0=embraye
Adh = [1]; % Risque de patinage des pneus
u = n;
Ch_ar = [rep*m*g];
% Nfx = [0];
% Sl = [0];

%% Simulation

while d < D_tot
    t = t+pas; 
    T = [T t];
    Ke = [Ke k]; % Memoire du rapport engage
    
    r = v/(k_p*K(k)*k_f*D_roue*3.14/60); % Calcul du regime moteur
       
    % Prise en compte du patinage de l'embrayage
        if k == 1            
            if r < r_pat
                E = [E, 1]; % Patinage
            else E = [E,0]; % Pas de patinage
            end
            r = max(r,r_pat);
        else E = [E,0];
        end
        R = [R r];
            
    % Acceleration
    if u < n || r > r_rupteur % Changement de rapport
        a=0; % Acceleration nulle si changement de rapport
        C = [C, 0]; % Memoire couple aux roues
        A = [A,0]; % Memoire acceleration
        Adh = [Adh, 0]; % Pas de risque de patinage des pneus
%         Nfx = [Nfx, 0]; % Pas de couple transmis
%         Sl = [Sl, 0];
        Ch_ar =[Ch_ar rep*m*g];
        
    else
        % Recherche du point de fonctionnement du moteur le plus proche dans les points connus
        r_dif = max(rmot);
        for i = 1:size(rmot,2)
           if abs(rmot(i)-r)<r_dif
               r_dif = abs(rmot(i)-r);
               j_k=i;
            end
        end
        
        c_k = couple(j_k,k); % Couple a la roue au rapport engage (m.kg)
        a_ang = (c_k*10-c_roul)/J_eq; % Acceleration angulaire des roues arrieres
        C_ar = rep*m*g+m*a*h_g/emp; % Charge sur l'essieu arri?re avec prise en compte du transfert de masse
        if C_ar > m*g % Cas ou les roues avant se soulevent
            C_ar = m*g;
        end
        c_trans_ar = coeff_adh*C_ar*D_roue/2; % Couple maximum transmissible
        
            if c_k > c_trans_ar/10 % Risque de d?passement des capacit?s du pneu
                Adh = [Adh,1];
                c_k = c_trans_ar/10; % Prise en compte de la limite d'adherence des pneus
                a_ang = (c_k*10-c_roul)/J_eq; % Acceleration angulaire des roues arrieres
                C_ar = rep*m*g+m*a*h_g/emp; % Charge sur l'essieu arri?re avec prise en compte du transfert de masse
             else Adh = [Adh, 0];
            end   
        C = [C, c_k]; % Memoire du couple aux roues         
        Ch_ar =[Ch_ar C_ar]; % Memoire de la charge sur les roues arrieres
        
        a = (1-sl)*a_ang*D_roue/2; % Acceleration du vehicule en m/s?
        A = [A,a/10]; % Memoire acceleration en g
    end
    v = v + a*pas; % Vitesse du vehicule
    V = [V v]; % Memoire de la vitesse
    d = d + v*pas; % Distance parcourue
    D = [D, d]; % Memoire de la distance
    r = v/(k_p*K(k)*k_f*D_roue*3.14/60); % Calcul du regime moteur
    
    % Changement de rapport
    
    % Changement au rupteur
    
    if r > r_rupteur && u >n && k<6 && k<k_max
        k = k+1;
        u=0;
        R_pas = [R_pas, r_rupteur];
        T_pas = [T_pas, t];
               
    % Changement de rapport pour optimiser le couple
    elseif k<k_max && u >n
     
        % Determination du regime le plus proche dans les points de fonctionnement connus
        r_dif = max(rmot);
        for i = 1:size(rmot,2)
           if abs(rmot(i)-r)<r_dif
               r_dif = abs(rmot(i)-r);
               j_k=i;
           end
        end
        c_k = couple(j_k,k); % Couple a la roue au rapport engage

        r_sup = v/(k_p*K(k+1)*k_f*D_roue*3.14/60); % Calcul du regime moteur au rapport superieur
        r_dif = max(rmot);
        for i = 1:size(rmot,2)
           if abs(rmot(i)-r_sup)<r_dif
               r_dif = abs(rmot(i)-r_sup);
               j_ksup=i;
           end
        end
        c_ksup = couple(j_ksup,k+1); % couple a la roue au rapport superieur
        
        if c_ksup > c_k && k<6 % Condition de changement de rapport
        k = k+1;
        u=0;
        R_pas = [R_pas, r];
        T_pas = [T_pas, t];
        end
    end
    u = u+1;                      
end

        end
    app.TimesEditField.Value = t ;

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AccelerationLabel = uilabel(app.UIFigure);
            app.AccelerationLabel.Text = 'Acceleration';
            app.VehicleMasskgEditFieldLabel = uilabel(app.UIFigure);
            app.VehicleMasskgEditFieldLabel.Text = 'Vehicle Mass (kg)';
            app.VehicleMasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.DriverMasskgEditFieldLabel = uilabel(app.UIFigure);
            app.DriverMasskgEditFieldLabel.Text = 'Driver Mass (kg)';
            app.DriverMasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.WheelbasemEditFieldLabel = uilabel(app.UIFigure);
            app.WheelbasemEditFieldLabel.Text = 'Wheelbase (m)';
            app.WheelbasemEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrackmEditFieldLabel = uilabel(app.UIFigure);
            app.TrackmEditFieldLabel.Text = 'Track (m)';
            app.TrackmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Acceleration vs Time')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, {'Acceleration (m/s^2)'; ''})
            app.TimesEditFieldLabel = uilabel(app.UIFigure);
            app.TimesEditFieldLabel.Text = 'Time (s)';
            app.TimesEditField = uieditfield(app.UIFigure, 'text');
            app.TimesEditField.Value = '50';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Accel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end