classdef GES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        DynamicEventSimulationMenu    matlab.ui.container.Menu
        SuspensionModelsMenu          matlab.ui.container.Menu
        PowertrainModelsMenu          matlab.ui.container.Menu
        FrameModelsMenu               matlab.ui.container.Menu
        ElectricalModelsMenu          matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        GESGLOBALEPSASIMULATIONLabel  matlab.ui.control.Label
        InformationPanel              matlab.ui.container.Panel
        Lastupdate07082019Label       matlab.ui.control.Label
        Currentversionv03Label        matlab.ui.control.Label
        CreatedbyNGOBATLabel          matlab.ui.control.Label
        DynamicEventSimulationPanel   matlab.ui.container.Panel
        AccelerationLabel             matlab.ui.control.Label
        SkidpadLabel                  matlab.ui.control.Label
        AutoXLabel                    matlab.ui.control.Label
        EnduranceLabel                matlab.ui.control.Label
        EfficiencyLabel               matlab.ui.control.Label
        TimesEditFieldLabel           matlab.ui.control.Label
        TimesEditField                matlab.ui.control.NumericEditField
        ScoreTotalEditFieldLabel      matlab.ui.control.Label
        ScoreTotalEditField           matlab.ui.control.NumericEditField
        TimesEditField_2Label         matlab.ui.control.Label
        TimesEditField_2              matlab.ui.control.NumericEditField
        TimesEditField_3Label         matlab.ui.control.Label
        TimesEditField_3              matlab.ui.control.NumericEditField
        TimesEditField_4Label         matlab.ui.control.Label
        TimesEditField_4              matlab.ui.control.NumericEditField
        volumeLEditFieldLabel         matlab.ui.control.Label
        volumeLEditField              matlab.ui.control.NumericEditField
        ScoreEditFieldLabel           matlab.ui.control.Label
        ScoreEditField                matlab.ui.control.NumericEditField
        ScoreEditField_2Label         matlab.ui.control.Label
        ScoreEditField_2              matlab.ui.control.NumericEditField
        ScoreEditField_3Label         matlab.ui.control.Label
        ScoreEditField_3              matlab.ui.control.NumericEditField
        ScoreEditField_4Label         matlab.ui.control.Label
        ScoreEditField_4              matlab.ui.control.NumericEditField
        ScoreEditField_5Label         matlab.ui.control.Label
        ScoreEditField_5              matlab.ui.control.NumericEditField
        InputPanel                    matlab.ui.container.Panel
        BrowseButton                  matlab.ui.control.Button
        ImportButton                  matlab.ui.control.Button
        FilenameLabel                 matlab.ui.control.Label
        StepSpinnerLabel              matlab.ui.control.Label
        StepSpinner                   matlab.ui.control.Spinner
        InProgressLabel               matlab.ui.control.Label
        SimulateButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        filename % Description
        Range
        Sheet
        file
        %Suspension Parameters
        %Powertrain Parameters
        %Aerodynamic Parameters
        %Genral Parameters
        TimeSimulation %Applicatio pour simulation de Temps aux Dynamic Events
    end
    

    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)

             [app.file,path] =  uigetfile('*.xlsx','Select an Excel file');
              
             if app.file ~= 0 
                 if strcmp('.xlsx',app.file(end-4:end)) %Le -4 c'est parce que le format s'écrit en 4 lettre : xlsx
                    app.filename = fullfile(path,app.file);
                    app.ImportButton.Enable = 'on';
                    app.FilenameLabel.Text = strcat('File name :',{' '},app.file);
                    app.InProgressLabel.Text = strcat(app.filename,{' '},' is ready for importation');
                 else
                     app.InProgressLabel.Text = 'You have to import an Excel file';
                 end
             end
        end

        % Callback function
        function AccelerationButtonValueChanged(app, event)
        
            
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.filename = app.file; %Name of the Excel file for the vehicle setup
            app.Sheet = 1 ; %Number of the sheet
            app.Range = 'C4' ; %Range for the value

            %Find the last row to add the new times
            [num, txt, raw] = xlsread(app.filename,app.Sheet,app.Range) ;
            disp(num)
            
            %Status Bar Update :
            app.InProgressLabel.Text = 'You have imported an Excel file';
           
        end

        % Callback function
        function AccelerationButtonPushed(app, event)
            %run('C:\Users\Gameiro Nicolas\Documents\EPSA\Intergen\02_Modèles\Lap_Time_Model\01_Simple_Model\GES')
            %Accel
            
            
            %Status Bar Update :
            app.InProgressLabel.Text = 'You have launched Accel simulation';
        end

        % Callback function
        function SkidpadButtonPushed(app, event)
            %__Test Turn (skidpad)__
            % 
            step = app.StepSpinner.Value ;
            R_turn = 8.5;
            A_turn = 360;
            Turn
            disp('Temps au skidpad :')
            disp(t_turn(end))
            
            %Status Bar Update :
            app.InProgressLabel.Text = 'You have launched Skidpad simulation';
        end

        % Callback function
        function EnduranceButtonPushed(app, event)
            GES_Time
            
            %Status Bar Update :
            app.InProgressLabel.Text = 'You have launched Endurance simulation';
        end

        % Menu selected function: DynamicEventSimulationMenu
        function DynamicEventSimulationMenuSelected(app, event)
            app.TimeSimulation = appTimeSimulation(app) ; 
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            GES_Time
             %Status Bar Update :
            app.InProgressLabel.Text = 'You have launched Lap Time simulation';
%             app.TimesEditField.Value = t_acc(end) ; 
%             app.TimesEditField_2.Value = t_turn(end) ;
%             app.TimesEditField_3.Value = t(end)/22 ;
%             app.TimesEditField_4.Value = t(end) ;
            app.volumeLEditField.Value = 7 ;
        end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.DynamicEventSimulationMenu = uimenu(app.Menu);
            app.DynamicEventSimulationMenu.MenuSelectedFcn = createCallbackFcn(app, @DynamicEventSimulationMenuSelected, true);
            app.DynamicEventSimulationMenu.Text = 'Dynamic Event Simulation';
            app.SuspensionModelsMenu = uimenu(app.Menu);
            app.SuspensionModelsMenu.Text = 'Suspension Models';
            app.PowertrainModelsMenu = uimenu(app.Menu);
            app.PowertrainModelsMenu.Text = 'Powertrain Models';
            app.FrameModelsMenu = uimenu(app.Menu);
            app.FrameModelsMenu.Text = 'Frame Models';
            app.ElectricalModelsMenu = uimenu(app.Menu);
            app.ElectricalModelsMenu.Text = 'Electrical Models';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.GESGLOBALEPSASIMULATIONLabel = uilabel(app.UIFigure);
            app.GESGLOBALEPSASIMULATIONLabel.Text = 'GES - GLOBAL EPSA SIMULATION';
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information';
            app.Lastupdate07082019Label = uilabel(app.InformationPanel);
            app.Lastupdate07082019Label.Text = 'Last update : 07/08/2019';
            app.Currentversionv03Label = uilabel(app.InformationPanel);
            app.Currentversionv03Label.Text = 'Current version : v0.3';
            app.CreatedbyNGOBATLabel = uilabel(app.InformationPanel);
            app.CreatedbyNGOBATLabel.Text = 'Created by : NGO & BAT';
            app.DynamicEventSimulationPanel = uipanel(app.UIFigure);
            app.DynamicEventSimulationPanel.Title = 'Dynamic Event Simulation';
            app.AccelerationLabel = uilabel(app.DynamicEventSimulationPanel);
            app.AccelerationLabel.Text = 'Acceleration';
            app.SkidpadLabel = uilabel(app.DynamicEventSimulationPanel);
            app.SkidpadLabel.Text = 'Skidpad';
            app.AutoXLabel = uilabel(app.DynamicEventSimulationPanel);
            app.AutoXLabel.Text = 'AutoX';
            app.EnduranceLabel = uilabel(app.DynamicEventSimulationPanel);
            app.EnduranceLabel.Text = 'Endurance';
            app.EfficiencyLabel = uilabel(app.DynamicEventSimulationPanel);
            app.EfficiencyLabel.Text = 'Efficiency';
            app.TimesEditFieldLabel = uilabel(app.DynamicEventSimulationPanel);
            app.TimesEditFieldLabel.Text = 'Time (s)';
            app.TimesEditField = uieditfield(app.DynamicEventSimulationPanel, 'numeric');
            app.ScoreTotalEditFieldLabel = uilabel(app.DynamicEventSimulationPanel);
            app.ScoreTotalEditFieldLabel.Text = 'Score Total';
            app.ScoreTotalEditField = uieditfield(app.DynamicEventSimulationPanel, 'numeric');
            app.TimesEditField_2Label = uilabel(app.DynamicEventSimulationPanel);
            app.TimesEditField_2Label.Text = 'Time (s)';
            app.TimesEditField_2 = uieditfield(app.DynamicEventSimulationPanel, 'numeric');
            app.TimesEditField_3Label = uilabel(app.DynamicEventSimulationPanel);
            app.TimesEditField_3Label.Text = 'Time (s)';
            app.TimesEditField_3 = uieditfield(app.DynamicEventSimulationPanel, 'numeric');
            app.TimesEditField_4Label = uilabel(app.DynamicEventSimulationPanel);
            app.TimesEditField_4Label.Text = 'Time (s)';
            app.TimesEditField_4 = uieditfield(app.DynamicEventSimulationPanel, 'numeric');
            app.volumeLEditFieldLabel = uilabel(app.DynamicEventSimulationPanel);
            app.volumeLEditFieldLabel.Text = 'volume (L)';
            app.volumeLEditField = uieditfield(app.DynamicEventSimulationPanel, 'numeric');
            app.ScoreEditFieldLabel = uilabel(app.DynamicEventSimulationPanel);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.DynamicEventSimulationPanel, 'numeric');
            app.ScoreEditField_2Label = uilabel(app.DynamicEventSimulationPanel);
            app.ScoreEditField_2Label.Text = 'Score';
            app.ScoreEditField_2 = uieditfield(app.DynamicEventSimulationPanel, 'numeric');
            app.ScoreEditField_3Label = uilabel(app.DynamicEventSimulationPanel);
            app.ScoreEditField_3Label.Text = 'Score';
            app.ScoreEditField_3 = uieditfield(app.DynamicEventSimulationPanel, 'numeric');
            app.ScoreEditField_4Label = uilabel(app.DynamicEventSimulationPanel);
            app.ScoreEditField_4Label.Text = 'Score';
            app.ScoreEditField_4 = uieditfield(app.DynamicEventSimulationPanel, 'numeric');
            app.ScoreEditField_5Label = uilabel(app.DynamicEventSimulationPanel);
            app.ScoreEditField_5Label.Text = 'Score';
            app.ScoreEditField_5 = uieditfield(app.DynamicEventSimulationPanel, 'numeric');
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.BrowseButton = uibutton(app.InputPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ImportButton = uibutton(app.InputPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.FilenameLabel = uilabel(app.InputPanel);
            app.FilenameLabel.Text = 'File name :';
            app.StepSpinnerLabel = uilabel(app.InputPanel);
            app.StepSpinnerLabel.Text = 'Step';
            app.StepSpinner = uispinner(app.InputPanel);
            app.StepSpinner.Value = 0.01;
            app.InProgressLabel = uilabel(app.UIFigure);
            app.InProgressLabel.Text = {'InProgress'; ''};
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GES

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end