classdef G < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GESGLOBALEPSASIMULATIONLabel  matlab.ui.control.Label
        InformationPanel              matlab.ui.container.Panel
        Lastupdate17072019Label       matlab.ui.control.Label
        Currentversionv01Label_2      matlab.ui.control.Label
        CreatedbyNGOBATLabel          matlab.ui.control.Label
        DynamicEventSimulationPanel   matlab.ui.container.Panel
        AccelerationButton            matlab.ui.control.StateButton
        SkidpadButton                 matlab.ui.control.StateButton
        AutocrossButton               matlab.ui.control.StateButton
        EnduranceButton               matlab.ui.control.StateButton
        PowertrainPanel               matlab.ui.container.Panel
        EngineButton                  matlab.ui.control.StateButton
        IntakeButton                  matlab.ui.control.StateButton
        ExhaustButton                 matlab.ui.control.StateButton
        CoolingButton                 matlab.ui.control.StateButton
        SuspensionPanel               matlab.ui.container.Panel
        AntiRollBarButton             matlab.ui.control.StateButton
        TiremodelsButton              matlab.ui.control.StateButton
        AarmsButton                   matlab.ui.control.StateButton
        BrakingButton                 matlab.ui.control.StateButton
        InputPanel                    matlab.ui.container.Panel
        BrowseButton                  matlab.ui.control.Button
        ImportButton                  matlab.ui.control.Button
        FilenameLabel                 matlab.ui.control.Label
        InProgressLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        filename % Description
    end
    

    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)

             [file,path] =  uigetfile('*.mat','Select a log file');
              
             if file ~= 0 
                 if strcmp('.mat',file(end-3:end))
                    app.filename = fullfile(path,file);
                    app.ImportButton.Enable = 'on';
                    app.FilenameLabel.Text = strcat('File name :',{' '},file);
                    app.InProgressLabel.Text = strcat(app.filename,{' '},' is ready for importation');
                 else
                     app.InProgressLabel.text = 'You have to import a log file';
                 end
             end
        end

        % Value changed function: AccelerationButton
        function AccelerationButtonValueChanged(app, event)
            value = app.AccelerationButton.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GESGLOBALEPSASIMULATIONLabel = uilabel(app.UIFigure);
            app.GESGLOBALEPSASIMULATIONLabel.Text = 'GES - GLOBAL EPSA SIMULATION';
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information';
            app.Lastupdate17072019Label = uilabel(app.InformationPanel);
            app.Lastupdate17072019Label.Text = 'Last update : 17/07/2019';
            app.Currentversionv01Label_2 = uilabel(app.InformationPanel);
            app.Currentversionv01Label_2.Text = 'Current version : v0.1';
            app.CreatedbyNGOBATLabel = uilabel(app.InformationPanel);
            app.CreatedbyNGOBATLabel.Text = 'Created by : NGO & BAT';
            app.DynamicEventSimulationPanel = uipanel(app.UIFigure);
            app.DynamicEventSimulationPanel.Title = 'Dynamic Event Simulation';
            app.AccelerationButton = uibutton(app.DynamicEventSimulationPanel, 'state');
            app.AccelerationButton.ValueChangedFcn = createCallbackFcn(app, @AccelerationButtonValueChanged, true);
            app.AccelerationButton.Text = 'Acceleration';
            app.SkidpadButton = uibutton(app.DynamicEventSimulationPanel, 'state');
            app.SkidpadButton.Text = 'Skidpad';
            app.AutocrossButton = uibutton(app.DynamicEventSimulationPanel, 'state');
            app.AutocrossButton.Text = 'Autocross';
            app.EnduranceButton = uibutton(app.DynamicEventSimulationPanel, 'state');
            app.EnduranceButton.Text = 'Endurance';
            app.PowertrainPanel = uipanel(app.UIFigure);
            app.PowertrainPanel.Title = 'Powertrain';
            app.EngineButton = uibutton(app.PowertrainPanel, 'state');
            app.EngineButton.Text = 'Engine';
            app.IntakeButton = uibutton(app.PowertrainPanel, 'state');
            app.IntakeButton.Text = 'Intake';
            app.ExhaustButton = uibutton(app.PowertrainPanel, 'state');
            app.ExhaustButton.Text = 'Exhaust';
            app.CoolingButton = uibutton(app.PowertrainPanel, 'state');
            app.CoolingButton.Text = 'Cooling';
            app.SuspensionPanel = uipanel(app.UIFigure);
            app.SuspensionPanel.Title = 'Suspension';
            app.AntiRollBarButton = uibutton(app.SuspensionPanel, 'state');
            app.AntiRollBarButton.Text = 'Anti Roll Bar';
            app.TiremodelsButton = uibutton(app.SuspensionPanel, 'state');
            app.TiremodelsButton.Text = 'Tire models';
            app.AarmsButton = uibutton(app.SuspensionPanel, 'state');
            app.AarmsButton.Text = 'A arms';
            app.BrakingButton = uibutton(app.SuspensionPanel, 'state');
            app.BrakingButton.Text = 'Braking';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.BrowseButton = uibutton(app.InputPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ImportButton = uibutton(app.InputPanel, 'push');
            app.ImportButton.Text = 'Import';
            app.FilenameLabel = uilabel(app.InputPanel);
            app.FilenameLabel.Text = 'File name :';
            app.InProgressLabel = uilabel(app.UIFigure);
            app.InProgressLabel.Text = {'InProgress'; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = G

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end