classdef GES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        DynamicEventSimulationMenu    matlab.ui.container.Menu
        SuspensionModelsMenu          matlab.ui.container.Menu
        PowertrainModelsMenu          matlab.ui.container.Menu
        FrameModelsMenu               matlab.ui.container.Menu
        ElectricalModelsMenu          matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        GESGLOBALEPSASIMULATIONLabel  matlab.ui.control.Label
        InformationPanel              matlab.ui.container.Panel
        Lastupdate07082019Label       matlab.ui.control.Label
        Currentversionv03Label        matlab.ui.control.Label
        CreatedbyNGOBATLabel          matlab.ui.control.Label
        DynamicEventSimulationPanel   matlab.ui.container.Panel
        SkidpadButton                 matlab.ui.control.Button
        AccelerationButton            matlab.ui.control.Button
        AutoXButton                   matlab.ui.control.Button
        EnduranceButton               matlab.ui.control.Button
        PowertrainPanel               matlab.ui.container.Panel
        EngineButton                  matlab.ui.control.StateButton
        IntakeButton                  matlab.ui.control.StateButton
        ExhaustButton                 matlab.ui.control.StateButton
        CoolingButton                 matlab.ui.control.StateButton
        SuspensionPanel               matlab.ui.container.Panel
        AntiRollBarButton             matlab.ui.control.StateButton
        TiremodelsButton              matlab.ui.control.StateButton
        AarmsButton                   matlab.ui.control.StateButton
        BrakingButton                 matlab.ui.control.StateButton
        InputPanel                    matlab.ui.container.Panel
        BrowseButton                  matlab.ui.control.Button
        ImportButton                  matlab.ui.control.Button
        FilenameLabel                 matlab.ui.control.Label
        StepSpinnerLabel              matlab.ui.control.Label
        StepSpinner                   matlab.ui.control.Spinner
        InProgressLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        filename % Description
        Range
        Sheet
        file
        %Suspension Parameters
        %Powertrain Parameters
        %Aerodynamic Parameters
        %Genral Parameters
        TimeSimulation %Applicatio pour simulation de Temps aux Dynamic Events
    end
    

    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)

             [app.file,path] =  uigetfile('*.xlsx','Select an Excel file');
              
             if app.file ~= 0 
                 if strcmp('.xlsx',app.file(end-4:end)) %Le -4 c'est parce que le format s'écrit en 4 lettre : xlsx
                    app.filename = fullfile(path,app.file);
                    app.ImportButton.Enable = 'on';
                    app.FilenameLabel.Text = strcat('File name :',{' '},app.file);
                    app.InProgressLabel.Text = strcat(app.filename,{' '},' is ready for importation');
                 else
                     app.InProgressLabel.Text = 'You have to import an Excel file';
                 end
             end
        end

        % Callback function
        function AccelerationButtonValueChanged(app, event)
        
            
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.filename = app.file; %Name of the Excel file for the vehicle setup
            app.Sheet = 1 ; %Number of the sheet
            app.Range = 'C4' ; %Range for the value

            %Find the last row to add the new times
            [num, txt, raw] = xlsread(app.filename,app.Sheet,app.Range) ;
            disp(num)
            
            %Status Bar Update :
            app.InProgressLabel.Text = 'You have imported an Excel file';
           
        end

        % Button pushed function: AccelerationButton
        function AccelerationButtonPushed(app, event)
            %run('C:\Users\Gameiro Nicolas\Documents\EPSA\Intergen\02_Modèles\Lap_Time_Model\01_Simple_Model\GES')
            %Accel
            
            
            %Status Bar Update :
            app.InProgressLabel.Text = 'You have launched Accel simulation';
        end

        % Button pushed function: SkidpadButton
        function SkidpadButtonPushed(app, event)
            %__Test Turn (skidpad)__
            % 
            step = app.StepSpinner.Value ;
            R_turn = 8.5;
            A_turn = 360;
            Turn
            disp('Temps au skidpad :')
            disp(t_turn(end))
            
            %Status Bar Update :
            app.InProgressLabel.Text = 'You have launched Skidpad simulation';
        end

        % Button pushed function: EnduranceButton
        function EnduranceButtonPushed(app, event)
            GES
            
            %Status Bar Update :
            app.InProgressLabel.Text = 'You have launched Endurance simulation';
        end

        % Menu selected function: DynamicEventSimulationMenu
        function DynamicEventSimulationMenuSelected(app, event)
            app.TimeSimulation = appTimeSimulation(app) ; 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.DynamicEventSimulationMenu = uimenu(app.Menu);
            app.DynamicEventSimulationMenu.MenuSelectedFcn = createCallbackFcn(app, @DynamicEventSimulationMenuSelected, true);
            app.DynamicEventSimulationMenu.Text = 'Dynamic Event Simulation';
            app.SuspensionModelsMenu = uimenu(app.Menu);
            app.SuspensionModelsMenu.Text = 'Suspension Models';
            app.PowertrainModelsMenu = uimenu(app.Menu);
            app.PowertrainModelsMenu.Text = 'Powertrain Models';
            app.FrameModelsMenu = uimenu(app.Menu);
            app.FrameModelsMenu.Text = 'Frame Models';
            app.ElectricalModelsMenu = uimenu(app.Menu);
            app.ElectricalModelsMenu.Text = 'Electrical Models';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.GESGLOBALEPSASIMULATIONLabel = uilabel(app.UIFigure);
            app.GESGLOBALEPSASIMULATIONLabel.Text = 'GES - GLOBAL EPSA SIMULATION';
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information';
            app.Lastupdate07082019Label = uilabel(app.InformationPanel);
            app.Lastupdate07082019Label.Text = 'Last update : 07/08/2019';
            app.Currentversionv03Label = uilabel(app.InformationPanel);
            app.Currentversionv03Label.Text = 'Current version : v0.3';
            app.CreatedbyNGOBATLabel = uilabel(app.InformationPanel);
            app.CreatedbyNGOBATLabel.Text = 'Created by : NGO & BAT';
            app.DynamicEventSimulationPanel = uipanel(app.UIFigure);
            app.DynamicEventSimulationPanel.Title = 'Dynamic Event Simulation';
            app.SkidpadButton = uibutton(app.DynamicEventSimulationPanel, 'push');
            app.SkidpadButton.ButtonPushedFcn = createCallbackFcn(app, @SkidpadButtonPushed, true);
            app.SkidpadButton.Text = 'Skidpad';
            app.AccelerationButton = uibutton(app.DynamicEventSimulationPanel, 'push');
            app.AccelerationButton.ButtonPushedFcn = createCallbackFcn(app, @AccelerationButtonPushed, true);
            app.AccelerationButton.Text = 'Acceleration';
            app.AutoXButton = uibutton(app.DynamicEventSimulationPanel, 'push');
            app.AutoXButton.Text = 'AutoX';
            app.EnduranceButton = uibutton(app.DynamicEventSimulationPanel, 'push');
            app.EnduranceButton.ButtonPushedFcn = createCallbackFcn(app, @EnduranceButtonPushed, true);
            app.EnduranceButton.Text = 'Endurance';
            app.PowertrainPanel = uipanel(app.UIFigure);
            app.PowertrainPanel.Title = 'Powertrain';
            app.EngineButton = uibutton(app.PowertrainPanel, 'state');
            app.EngineButton.Text = 'Engine';
            app.IntakeButton = uibutton(app.PowertrainPanel, 'state');
            app.IntakeButton.Text = 'Intake';
            app.ExhaustButton = uibutton(app.PowertrainPanel, 'state');
            app.ExhaustButton.Text = 'Exhaust';
            app.CoolingButton = uibutton(app.PowertrainPanel, 'state');
            app.CoolingButton.Text = 'Cooling';
            app.SuspensionPanel = uipanel(app.UIFigure);
            app.SuspensionPanel.Title = 'Suspension';
            app.AntiRollBarButton = uibutton(app.SuspensionPanel, 'state');
            app.AntiRollBarButton.Text = 'Anti Roll Bar';
            app.TiremodelsButton = uibutton(app.SuspensionPanel, 'state');
            app.TiremodelsButton.Text = 'Tire models';
            app.AarmsButton = uibutton(app.SuspensionPanel, 'state');
            app.AarmsButton.Text = 'A arms';
            app.BrakingButton = uibutton(app.SuspensionPanel, 'state');
            app.BrakingButton.Text = 'Braking';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.BrowseButton = uibutton(app.InputPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ImportButton = uibutton(app.InputPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.FilenameLabel = uilabel(app.InputPanel);
            app.FilenameLabel.Text = 'File name :';
            app.StepSpinnerLabel = uilabel(app.InputPanel);
            app.StepSpinnerLabel.Text = 'Step';
            app.StepSpinner = uispinner(app.InputPanel);
            app.StepSpinner.Value = 0.01;
            app.InProgressLabel = uilabel(app.UIFigure);
            app.InProgressLabel.Text = {'InProgress'; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = GES

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end