classdef appTimeSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TimeSimulationLabel         matlab.ui.control.Label
        ExportToExcelButton         matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        TimesEditFieldLabel         matlab.ui.control.Label
        TimesEditField              matlab.ui.control.EditField
        SkidpadButton               matlab.ui.control.Button
        AutoXButton                 matlab.ui.control.Button
        EnduranceButton             matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        PlotButton                  matlab.ui.control.Button
        AccelerationButton          matlab.ui.control.Button
        TimesEditField_2Label       matlab.ui.control.Label
        TimesEditField_2            matlab.ui.control.EditField
        ScoreEditFieldLabel         matlab.ui.control.Label
        ScoreEditField              matlab.ui.control.EditField
        ScoreEditField_2Label       matlab.ui.control.Label
        ScoreEditField_2            matlab.ui.control.EditField
        TimesEditField_3Label       matlab.ui.control.Label
        TimesEditField_3            matlab.ui.control.EditField
        ScoreEditField_3Label       matlab.ui.control.Label
        ScoreEditField_3            matlab.ui.control.EditField
        TimesEditField_4Label       matlab.ui.control.Label
        TimesEditField_4            matlab.ui.control.EditField
        ScoreEditField_4Label       matlab.ui.control.Label
        ScoreEditField_4            matlab.ui.control.EditField
        GPS                         matlab.ui.control.UIAxes
        DynamicEventDropDownLabel   matlab.ui.control.Label
        DynamicEventDropDown        matlab.ui.control.DropDown
        ConsumptionLEditFieldLabel  matlab.ui.control.Label
        ConsumptionLEditField       matlab.ui.control.EditField
        ScoreEditField_5Label       matlab.ui.control.Label
        ScoreEditField_5            matlab.ui.control.EditField
        DynamicEventscoreEditFieldLabel  matlab.ui.control.Label
        DynamicEventscoreEditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
            d_track
           
    end
    
    methods (Access = private)
        
            function [] = track_plot(app, X, Y, speed)
            % X et Y sont des coordonnées venant de lat_longi2X_Y
            % speed est en km/h
            cla(app.GPS,'reset') % reset le graph
            z = zeros(size(X'));
            col = speed';  % This is the color, vary with x in this case.
            surface(app.GPS, [X';X'],[Y';Y'],[z;z],[col;col],'facecol','no','edgecol','interp','linew',2);
            c = colorbar(app.GPS);
            xlabel(app.GPS, 'X (m)')
            ylabel(app.GPS, 'Y (m)')
            c.Label.String = 'Vitesse Km/h';
            axis(app.GPS,[min(app.X)-10,max(app.X)+10,min(app.Y)-10,max(app.Y)+10]);
            set(app.GPS,'Visible','off')
               end % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            GES
        end

        % Button pushed function: ExportToExcelButton
        function ExportToExcelButtonPushed(app, event)


        end

        % Button pushed function: AccelerationButton
        function AccelerationButtonPushed(app, event)
            
        end

        % Button pushed function: SkidpadButton
        function SkidpadButtonPushed(app, event)

            
            app.TimesEditField_2.Value = num2str(t_turn(end)) ;
            app.ScoreEditField.Value = num2str(score_skidpad) ;
        end

        % Button pushed function: EnduranceButton
        function EnduranceButtonPushed(app, event)

            app.TimesEditField_4.Value = num2str(t(end)) ;
            app.ScoreEditField_4.Value = num2str(score_endurance) ;

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            track_plot(interp1(d_track,X,d)',interp1(d_track,Y,d)',V')
            
            %Display
            app.InProgress.Text = strcat("X",'was imported succesfully');
            app.ExportToExcelButton.Visible = 'on';
            app.PlotButton.Enable = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TimeSimulationLabel = uilabel(app.UIFigure);
            app.TimeSimulationLabel.Text = 'Time Simulation ';
            app.ExportToExcelButton = uibutton(app.UIFigure, 'push');
            app.ExportToExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportToExcelButtonPushed, true);
            app.ExportToExcelButton.Text = 'Export To Excel';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Acceleration vs Time')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, {'Acceleration (m/s^2)'; ''})
            app.TimesEditFieldLabel = uilabel(app.UIFigure);
            app.TimesEditFieldLabel.Text = 'Time (s)';
            app.TimesEditField = uieditfield(app.UIFigure, 'text');
            app.TimesEditField.Value = '50';
            app.SkidpadButton = uibutton(app.UIFigure, 'push');
            app.SkidpadButton.ButtonPushedFcn = createCallbackFcn(app, @SkidpadButtonPushed, true);
            app.SkidpadButton.Text = 'Skidpad';
            app.AutoXButton = uibutton(app.UIFigure, 'push');
            app.AutoXButton.Text = 'AutoX';
            app.EnduranceButton = uibutton(app.UIFigure, 'push');
            app.EnduranceButton.ButtonPushedFcn = createCallbackFcn(app, @EnduranceButtonPushed, true);
            app.EnduranceButton.Text = 'Endurance';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Concept_Name'; 'Time'; 'Score'};
            app.UITable.RowName = {'Test'; 'Test2'};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.AccelerationButton = uibutton(app.UIFigure, 'push');
            app.AccelerationButton.ButtonPushedFcn = createCallbackFcn(app, @AccelerationButtonPushed, true);
            app.AccelerationButton.Text = 'Acceleration';
            app.TimesEditField_2Label = uilabel(app.UIFigure);
            app.TimesEditField_2Label.Text = 'Time (s)';
            app.TimesEditField_2 = uieditfield(app.UIFigure, 'text');
            app.TimesEditField_2.Value = '50';
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.UIFigure, 'text');
            app.ScoreEditField.Value = '50';
            app.ScoreEditField_2Label = uilabel(app.UIFigure);
            app.ScoreEditField_2Label.Text = 'Score';
            app.ScoreEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ScoreEditField_2.Value = '50';
            app.TimesEditField_3Label = uilabel(app.UIFigure);
            app.TimesEditField_3Label.Text = 'Time (s)';
            app.TimesEditField_3 = uieditfield(app.UIFigure, 'text');
            app.TimesEditField_3.Value = '50';
            app.ScoreEditField_3Label = uilabel(app.UIFigure);
            app.ScoreEditField_3Label.Text = 'Score';
            app.ScoreEditField_3 = uieditfield(app.UIFigure, 'text');
            app.ScoreEditField_3.Value = '50';
            app.TimesEditField_4Label = uilabel(app.UIFigure);
            app.TimesEditField_4Label.Text = 'Time (s)';
            app.TimesEditField_4 = uieditfield(app.UIFigure, 'text');
            app.TimesEditField_4.Value = '50';
            app.ScoreEditField_4Label = uilabel(app.UIFigure);
            app.ScoreEditField_4Label.Text = 'Score';
            app.ScoreEditField_4 = uieditfield(app.UIFigure, 'text');
            app.ScoreEditField_4.Value = '50';
            app.GPS = uiaxes(app.UIFigure);
            title(app.GPS, 'GPS')
            xlabel(app.GPS, 'X')
            ylabel(app.GPS, 'Y')
            app.DynamicEventDropDownLabel = uilabel(app.UIFigure);
            app.DynamicEventDropDownLabel.Text = 'Dynamic Event';
            app.DynamicEventDropDown = uidropdown(app.UIFigure);
            app.DynamicEventDropDown.Items = {'Acceleration', 'Skidpad', 'AutoX', 'Endurance'};
            app.DynamicEventDropDown.Value = 'Acceleration';
            app.ConsumptionLEditFieldLabel = uilabel(app.UIFigure);
            app.ConsumptionLEditFieldLabel.Text = 'Consumption (L)';
            app.ConsumptionLEditField = uieditfield(app.UIFigure, 'text');
            app.ConsumptionLEditField.Value = '50';
            app.ScoreEditField_5Label = uilabel(app.UIFigure);
            app.ScoreEditField_5Label.Text = 'Score';
            app.ScoreEditField_5 = uieditfield(app.UIFigure, 'text');
            app.ScoreEditField_5.Value = '50';
            app.DynamicEventscoreEditFieldLabel = uilabel(app.UIFigure);
            app.DynamicEventscoreEditFieldLabel.Text = 'Dynamic Event score :';
            app.DynamicEventscoreEditField = uieditfield(app.UIFigure, 'text');
            app.DynamicEventscoreEditField.Value = '50';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appTimeSimulation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end